classdef hw4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EntercityLabel              matlab.ui.control.Label
        EntercityEditField          matlab.ui.control.EditField
        GoButton                    matlab.ui.control.Button
        HowfarkmLabel               matlab.ui.control.Label
        HowfarkmEditField           matlab.ui.control.EditField
        ResultPanel                 matlab.ui.container.Panel
        GoodcityornotLabel          matlab.ui.control.Label
        LampLabel                   matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        OthercitiesTextAreaLabel    matlab.ui.control.Label
        OthercitiesTextArea         matlab.ui.control.TextArea
        UIAxes                      matlab.ui.control.UIAxes
        CreatedbyERSincLabel        matlab.ui.control.Label
        BestCityQualityofLifeLabel  matlab.ui.control.Label
    end


    methods (Access = private)

        % Callback function
        function size(app, event)

        end

        % Button pushed function: GoButton
        function press(app, event)
            city = app.EntercityEditField.Value; %getting city input
            distance = app.HowfarkmEditField.Value; %getting distance max input
            
            [cityLocation, cityStates, howFar] = getLocation(city,distance); %getting list of close cities
            
            if isa(cityLocation,'double') %checking if city input is in the data set
                app.LampLabel.Text = 'Not Found';
                app.Lamp.Color = [1 1 0];
            else 
                dataCell = getData(cityLocation, cityStates); %getting cell array with the data from the close cities
        
                [cityGood, howFarGood] = getGoods(dataCell, howFar); %getting all cities which are good to live from the close cities list
                app.OthercitiesTextArea.Value = cityGood; %printing good cities to live


                %LAMP
                count = 0;
                for i=1:length(cityGood) %checking if the city input is good or not
                    if strcmp(cityLocation{i,1},city) 
                        app.Lamp.Color = [0 1 0];
                        app.LampLabel.Text = 'Good';
                        count = 1;
                    end
                end 
                if count ~= 1
                    app.Lamp.Color = [1 0 0];
                    app.LampLabel.Text = 'Bad';
                end
                clear i;


                %PLOTTING
                [ypoint, labels] = graphFun(howFarGood); %getting the closest and farthest cities
                x=(1:4);
                plot(app.UIAxes, x,ypoint); %plotting the closest and farthest cities
                text(app.UIAxes, x,ypoint,labels,'VerticalAlignment','bottom','HorizontalAlignment','right');
            
            end
           
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.EntercityLabel = uilabel(app.UIFigure);
            app.EntercityLabel.Text = 'Enter city:';
            app.EntercityEditField = uieditfield(app.UIFigure, 'text');
            app.GoButton = uibutton(app.UIFigure, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @press, true);
            app.GoButton.Text = 'Go';
            app.HowfarkmLabel = uilabel(app.UIFigure);
            app.HowfarkmLabel.Text = 'How far (km):';
            app.HowfarkmEditField = uieditfield(app.UIFigure, 'text');
            app.ResultPanel = uipanel(app.UIFigure);
            app.ResultPanel.Title = 'Result';
            app.GoodcityornotLabel = uilabel(app.ResultPanel);
            app.GoodcityornotLabel.Text = 'Good city or not:';
            app.LampLabel = uilabel(app.ResultPanel);
            app.LampLabel.Text = '';
            app.Lamp = uilamp(app.ResultPanel);
            app.OthercitiesTextAreaLabel = uilabel(app.ResultPanel);
            app.OthercitiesTextAreaLabel.Text = 'Other cities:';
            app.OthercitiesTextArea = uitextarea(app.ResultPanel);
            app.UIAxes = uiaxes(app.ResultPanel);
            xlabel(app.UIAxes, 'Cities')
            ylabel(app.UIAxes, 'Distance')
            app.CreatedbyERSincLabel = uilabel(app.UIFigure);
            app.CreatedbyERSincLabel.Text = 'Created by ERS.inc';
            app.BestCityQualityofLifeLabel = uilabel(app.UIFigure);
            app.BestCityQualityofLifeLabel.Text = 'Best City, Quality of Life';
        end
    end

    methods (Access = public)

        % Construct app
        function app = hw4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end